classdef AdhesionAssayForceBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdhesionAssayForceBrowserUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenAdhesiondatasetMenu   matlab.ui.container.Menu
        SaveAsMenu                matlab.ui.container.Menu
        CloseAdhesionDatasetMenu  matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        EditMenu                  matlab.ui.container.Menu
        ConfigMenu                matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        LeftPanel                 matlab.ui.container.Panel
        OneModeButton             matlab.ui.control.Button
        StartingPointTable        matlab.ui.control.Table
        ActiveFitData             matlab.ui.control.Table
        AdhesionTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        ForcePlot       % Handle to force plot used inside the app
        OpenedFilename  % Filename that's opened (put here for default save filename)
        AdhesionDataset % Pristine copy of the opened data file. Modifications are made outside this variable
    end
    
    methods (Access = private)
        
        function Data = update_DetachForceTable(app, newfit, Curve, AdhesionSelectedRow)

            Data = app.AdhesionTable.Data;
            
            Data.FitObject{AdhesionSelectedRow} = newfit.FitObject;
            Data.sse(AdhesionSelectedRow) = newfit.sse;
            Data.rsquare(AdhesionSelectedRow) = newfit.rsquare;
            Data.dfe(AdhesionSelectedRow) = newfit.dfe;
            Data.adjrsquare(AdhesionSelectedRow) = newfit.adjrsquare;
            Data.rmse(AdhesionSelectedRow) = newfit.rmse;
            Data.a(AdhesionSelectedRow) = newfit.a;
            Data.aconf(AdhesionSelectedRow,:) = newfit.aconf;
            Data.am(AdhesionSelectedRow) = newfit.am;
            Data.amconf(AdhesionSelectedRow,:) = newfit.amconf;
            Data.as(AdhesionSelectedRow) = newfit.as;
            Data.asconf(AdhesionSelectedRow,:) = newfit.asconf;
            Data.Nterms(AdhesionSelectedRow,:)  = newfit.Nterms;

%             try
                Data.bm(AdhesionSelectedRow) = newfit.bm;
                Data.bmconf(AdhesionSelectedRow,:) = newfit.bmconf;
                Data.bs(AdhesionSelectedRow) = newfit.bs;
                Data.bsconf(AdhesionSelectedRow,:) = newfit.bsconf;
%             catch
%                 Data.bm(AdhesionSelectedRow) = NaN;
%                 Data.bmconf(AdhesionSelectedRow,:) = [NaN NaN];
%                 Data.bs(AdhesionSelectedRow) = NaN;
%                 Data.bsconf(AdhesionSelectedRow,:) = [NaN NaN];
%             end

            Data.RawData{AdhesionSelectedRow} = Curve;

            if newfit.a > 0.5
                Data.DetachForce(AdhesionSelectedRow) = newfit.am;
                Data.confDetachForce(AdhesionSelectedRow,:) = newfit.amconf;
            else
                Data.DetachForce(AdhesionSelectedRow) = newfit.bm;
                Data.confDetachForce(AdhesionSelectedRow,:) = newfit.bmconf;
            end  

            assignin("base", "AdhesionAppDataSet", Data);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenAdhesiondatasetMenu
        function OpenAdhesiondatasetMenuSelected(app, event)
            [File, Path, fidx] = uigetfile({'*.mat;*.AdhesionDataset.mat';'*.mat';'*.AdhesionDataset.mat';'*.*'}, ...
                                      'Select File(s) to Open', ...
                                      'MultiSelect', 'off');

            app.OpenedFilename = fullfile(Path, File);
            app.AdhesionDataset = load(app.OpenedFilename);
            
%             if isfield(app.AdhesionDataset, 'DetachForceTable')
                Data = app.AdhesionDataset.DetachForceTable;     
%             elseif isfield(app.AdhesionDataset, 'PlateID')
%                 Data = app.AdhesionDataset;
%             end

            app.AdhesionTable.Data = Data;
            app.AdhesionTable.ColumnName = Data.Properties.VariableNames;            
            drawnow;
            app.AdhesionTable.RowName = 'numbered';            
            app.AdhesionTable.SelectionType = "row";
            drawnow;

            app.StartingPointTable.ColumnName = {'StartPoint'};
            app.StartingPointTable.RowName = {'a'; 'am'; 'as'; 'bm'; 'bs'};
            app.StartingPointTable.Data = [0.825816977489547 ; 0.0781755287531837 ; 0.442678269775446 ; 0.106652770180584 ; 0.961898080855054];
            app.StartingPointTable.SelectionType = "row";
            drawnow;

            app.ForcePlot = figure;

            assignin("base", "AdhesionAppDataSet", Data);
            logentry(['Loaded file: ' File]);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.ForcePlot);
            app.delete;
        end

        % Callback function
        function ReduceFitButtonPushed(app, event)


        end

        % Selection changed function: AdhesionTable
        function AdhesionTableSelectionChanged(app, event)
            selection = app.AdhesionTable.Selection;
            
            selData = app.AdhesionTable.Data(selection,:);

            myfit = selData.FitObject{1};
            RawData = selData.RawData{1};
            titlestring = join(string(table2array(selData(1, {'BeadChemistry', 'SubstrateChemistry', 'Media', 'pH'}))), '_');

            ActiveFitDataOut.fit = myfit;
            ActiveFitDataOut.RawData = RawData;
            assignin("base", "ActiveFitDataOut", ActiveFitDataOut);

            try
                        fo = fitoptions(myfit);
                        app.StartingPointTable.Data = reshape(fo.StartPoint,[],1);
            catch
                warning('No data to fit. UI data may be stale.')
            end
            
            inc = RawData.IncludeInFit;
            ba_plot_fit(myfit, RawData.Force(inc), RawData.ForceError(inc), RawData.PctLeft(inc), app.ForcePlot);
            figure(app.ForcePlot);
            hold on
                plot(log10(RawData.Force(~inc)), RawData.PctLeft(~inc), 'kx');
            hold off
            title(titlestring, 'Interpreter', 'none');

            app.ActiveFitData.Data = RawData;
            app.ActiveFitData.ColumnName = RawData.Properties.VariableNames;            
            drawnow;
            app.ActiveFitData.RowName = 'numbered';            
            app.ActiveFitData.SelectionType = "row";            
            drawnow;

        end

        % Cell edit callback: ActiveFitData
        function ActiveFitDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            AdhesionSelectedRow = app.AdhesionTable.Selection;

            Curve = event.Source.Data;
            Data = app.AdhesionTable.Data;

            inc = Curve.IncludeInFit;

            force = Curve.Force;
            force_error = Curve.ForceError;
            pct_left = Curve.PctLeft;
            weights = Curve.Weights;
            
            Nterms = app.AdhesionTable.Data.Nterms(AdhesionSelectedRow);

            
            StartingPoint = reshape(app.StartingPointTable.Data, 1, []);
            app.StartingPointTable.Data = reshape(StartingPoint, [], 1);

            newfit = ba_fit_erf(log10(force(inc)), pct_left(inc), weights(inc), Nterms, StartingPoint);

            ba_plot_fit(newfit.FitObject, force(inc), force_error(inc), pct_left(inc), app.ForcePlot);
            figure(app.ForcePlot)
            hold on
                plot(log10(force(~inc)), pct_left(~inc), 'kx');
            hold off

            Data = update_DetachForceTable(app, newfit, Curve, AdhesionSelectedRow);

            app.AdhesionTable.Data = Data;
%             assignin("base", "ActiveFitDataOut", ActiveFitDataOut);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            
            DataOut = app.AdhesionDataset;
            DataOut.DetachForceTable = app.AdhesionTable.Data;
            
            DefaultSaveName = strrep(app.OpenedFilename,'.mat', '');
            DefaultSaveName = [DefaultSaveName, '.UIedited.mat'];
            [File, Path, fidx] = uiputfile({'*.UIedited.mat', 'AdhesionUI edited files (*.UIedited.mat)' ; ...
                                           '*.*', 'All Files (*.*)'}, ...
                                           'Save As...', ...
                                           DefaultSaveName);

            savename = fullfile(Path, File);
            save(savename,'-STRUCT', "DataOut",  '-mat');
    
            logentry(['Saved file: ' File]);
        end

        % Cell edit callback: StartingPointTable
        function StartingPointTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            StartingPoint = reshape(event.Source.Data, 1, []);
            app.StartingPointTable.Data = reshape(StartingPoint, [], 1);

            AdhesionSelectedRow = app.AdhesionTable.Selection;

            Curve = app.AdhesionTable.Data.RawData{AdhesionSelectedRow};
%             Data = app.AdhesionTable.Data;

            inc = Curve.IncludeInFit;

            force = Curve.Force;
            force_error = Curve.ForceError;
            pct_left = Curve.PctLeft;
            weights = Curve.Weights;
            
            Nterms = app.AdhesionTable.Data.Nterms(AdhesionSelectedRow);

            newfit = ba_fit_erf(log10(force(inc)), pct_left(inc), weights(inc), Nterms, StartingPoint);

            ba_plot_fit(newfit.FitObject, force(inc), force_error(inc), pct_left(inc), app.ForcePlot);
            figure(app.ForcePlot)
            hold on
                plot(log10(force(~inc)), pct_left(~inc), 'kx');
            hold off

            app.AdhesionTable.Data = update_DetachForceTable(app, newfit, Curve, AdhesionSelectedRow);
            
        end

        % Button pushed function: OneModeButton
        function OneModeButtonPushed(app, event)
             AdhesionSelectedRow = app.AdhesionTable.Selection;

            Curve = app.AdhesionTable.Data.RawData{AdhesionSelectedRow};

            StartingPoint = reshape(app.StartingPointTable.Data, 1, []);
            StartingPoint = StartingPoint(1,1:3);
            app.StartingPointTable.Data = reshape(StartingPoint, [], 1);

            inc = Curve.IncludeInFit;

            force = Curve.Force;
            force_error = Curve.ForceError;
            pct_left = Curve.PctLeft;
            weights = Curve.Weights;
            
            Nterms = 1;
            
            newfit = ba_fit_erf(log10(force(inc)), pct_left(inc), weights(inc), Nterms, StartingPoint);

            ba_plot_fit(newfit.FitObject, force(inc), force_error(inc), pct_left(inc), app.ForcePlot);
            figure(app.ForcePlot)
            hold on
                plot(log10(force(~inc)), pct_left(~inc), 'kx');
            hold off

            app.AdhesionTable.Data = update_DetachForceTable(app, newfit, Curve, AdhesionSelectedRow);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdhesionAssayForceBrowserUIFigure = uifigure('Visible', 'off');
            app.AdhesionAssayForceBrowserUIFigure.Name = 'Adhesion Assay Force Browser';
            app.FileMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenAdhesiondatasetMenu = uimenu(app.FileMenu);
            app.OpenAdhesiondatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenAdhesiondatasetMenuSelected, true);
            app.OpenAdhesiondatasetMenu.Accelerator = 'o';
            app.OpenAdhesiondatasetMenu.Text = 'Open Adhesion dataset';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseAdhesionDatasetMenu = uimenu(app.FileMenu);
            app.CloseAdhesionDatasetMenu.Accelerator = 'w';
            app.CloseAdhesionDatasetMenu.Text = 'Close Adhesion Dataset';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'q';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ConfigMenu = uimenu(app.EditMenu);
            app.ConfigMenu.Accelerator = ',';
            app.ConfigMenu.Text = 'Config';
            app.HelpMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.LeftPanel = uipanel(app.AdhesionAssayForceBrowserUIFigure);
            app.AdhesionTable = uitable(app.LeftPanel);
            app.AdhesionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AdhesionTable.ColumnRearrangeable = 'on';
            app.AdhesionTable.RowName = {};
            app.AdhesionTable.ColumnSortable = true;
            app.AdhesionTable.SelectionType = 'row';
            app.AdhesionTable.ColumnEditable = true;
            app.AdhesionTable.SelectionChangedFcn = createCallbackFcn(app, @AdhesionTableSelectionChanged, true);
            app.ActiveFitData = uitable(app.LeftPanel);
            app.ActiveFitData.ColumnName = '';
            app.ActiveFitData.RowName = {};
            app.ActiveFitData.ColumnSortable = true;
            app.ActiveFitData.ColumnEditable = true;
            app.ActiveFitData.CellEditCallback = createCallbackFcn(app, @ActiveFitDataCellEdit, true);
            app.StartingPointTable = uitable(app.LeftPanel);
            app.StartingPointTable.ColumnName = {'Value'};
            app.StartingPointTable.RowName = {'a'; 'am'; 'as'; 'bm'; 'bs'};
            app.StartingPointTable.ColumnEditable = true;
            app.StartingPointTable.CellEditCallback = createCallbackFcn(app, @StartingPointTableCellEdit, true);
            app.StartingPointTable.Multiselect = 'off';
            app.OneModeButton = uibutton(app.LeftPanel, 'push');
            app.OneModeButton.ButtonPushedFcn = createCallbackFcn(app, @OneModeButtonPushed, true);
            app.OneModeButton.Text = 'Reduce to one mode';
            % Show the figure after all components are created
            app.AdhesionAssayForceBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdhesionAssayForceBrowser

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdhesionAssayForceBrowserUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.AdhesionAssayForceBrowserUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdhesionAssayForceBrowserUIFigure)
        end
    end
end